classdef DiceGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        StatusLabel     matlab.ui.control.Label
        SkipButton      matlab.ui.control.Button
        UITable         matlab.ui.control.Table
        dice2           matlab.ui.control.Image
        dice3           matlab.ui.control.Image
        dice4           matlab.ui.control.Image
        dice6           matlab.ui.control.Image
        dice5           matlab.ui.control.Image
        dice1           matlab.ui.control.Image
        RollDiceButton  matlab.ui.control.Button
        PlayButton      matlab.ui.control.Button
        secondbg        matlab.ui.control.Image
        menubg          matlab.ui.control.Image
    end

    
    properties (Access = private)
        Sensor
        GestureDetector
        Game% Description
        %% ThinkSpeak Online
        ChannelID = "3383934"
        WriteKey = "NPMTEL19I4BXYDHS"
        ReadKey = "RTC2OM3Q1X84MTGH"
        PlayerNum = 1 %Alternates between 1 and 2
        SyncTimer
    end
    
    methods (Access = private)
        
        function UpdateGameDisplay(app)


            % Update table with current scores and target
            app.UITable.Data = {app.Game.P1Score, app.Game.P2Score, app.Game.TargetNumber};

            if ~app.Game.GameOver
                statusText = app.Game.getTurnStatus();
                rollsLeft = app.Game.getRollsRemaining(); 
                if rollsLeft > 0
                    statusText = sprintf('%s (%d roll(s) left this turn)', statusText, rollsLeft);
                end
                app.StatusLabel.Text = statusText;
            else
                app.StatusLabel.Text = sprintf('GAME OVER! Player %d wins!', app.Game.Winner);
            end
            
            % Enable/disable buttons based on game state
            if ~app.Game.GameOver
                % Check if it's this player's turn (for online play)
                if app.Game.CurrentPlayer == app.PlayerNum
                    app.RollDiceButton.Enable = "on";
                    app.SkipButton.Enable = "on";
                else
                    app.RollDiceButton.Enable = "off";
                    app.SkipButton.Enable = "off";
                    app.StatusLabel.Text = sprintf('%s\nWaiting for Player %d...', ...
                        app.StatusLabel.Text, app.Game.CurrentPlayer);
                end
            end
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Start(app, event)
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
        %      answer = uiconfirm(app.UIFigure, 'Are you Player 1?', ...
        % 'Player Selection', 'Options', {'Yes, I am Player 1', 'No, I am Player 2'});
        % 
            app.menubg.Visible = "off";
            app.secondbg.Visible = "on";

            % okedjbioewhfipehipf

            % making dice on and roll button on
            %diceImages = {"one.png","two.png","three.png","four.png","five.png","six.png"};
            %app.Image6.ImageSource = "one.png";
            % for k= 1:numel(diceImages)
            %     app.Image5.ImageSource = diceImages(k);
            %     % change it every 0.1 seconds or so to simulate animation
            % end
            app.UITable.Visible = "on";
            app.RollDiceButton.Visible = "on";
            app.SkipButton.Visible = "on";
            app.dice1.Visible = "on";
            app.dice2.Visible = "off";
            app.dice3.Visible = "off";
            app.dice4.Visible = "off";
            app.dice5.Visible = "off";
            app.dice6.Visible = "off";

            %app.dice5.Visible = "on"; % FIXED: Added missing semicolon
            
            %Disable and make Play and Help buttons invisible
            app.PlayButton.Enable = "off";
            app.PlayButton.Visible = "off";

            %app.Sensor = sensorAnalyzer();
            %app.GestureDetector = GestureDetector(app.Sensor);
            app.Game = GameState();

            
        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)
          
            roll = app.Game.RollDice()
            
            % Group your 6 image components into a cell array
            diceComponents = {app.dice1, app.dice2, app.dice3, ...
                              app.dice4, app.dice5, app.dice6};
                          
            % Number of frames to shuffle
            numShuffles = 20; 
            
            % Helper function to hide all dice images
            for k = 1:6
                diceComponents{k}.Visible = "off";
            end
            
            % Shuffle animation loop
            for i = 1:numShuffles
                % Generate a random dice index (1 through 6)
                currentRoll = randi([1, 6]); 
                
                % Flash the current random dice image on
                diceComponents{currentRoll}.Visible = "on";
                
                % Pause briefly to create the illusion of rolling
                pause(0.08);
                
                % Turn it back off before the next iteration
                diceComponents{currentRoll}.Visible = "off";
                
                % Force the UI to refresh immediately
                drawnow(); 
            end
            
            % Determine and display the final roll

            diceComponents{roll}.Visible = "on";
            
           %Update UI
           app.UpdateGameDisplay();


            rollsLeft = app.Game.getRollsRemaining();
            if rollsLeft > 0
                app.StatusLabel.Text = sprintf('%s (%d roll(s) left this turn)', app.StatusLabel.Text, rollsLeft);
            end
            
            if app.Game.GameOver
                uialert(app.UIFigure, sprintf('Game Over! Player %d reached the limit! Player %d wins!', ...
                        3 - app.Game.Winner, app.Game.Winner), 'Game Over');
                app.RollDiceButton.Enable = "off";
                app.SkipButton.Enable = "off";
            end


        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            if app.Game.GameOver
            uialert(app.UIFigure, 'Game is over!', 'Game Over');
            return;
            end


            skippedPlayer = app.Game.CurrentPlayer;
            app.Game.skipTurn();

            % Penalty Message
            uialert(app.UIFigure, sprintf('Player %d skipped their turn!\nThey will have to roll twice on their next turn.', ...
                    skippedPlayer), 'Turn Skipped');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.menubg = uiimage(app.UIFigure);
            app.menubg.ImageSource = fullfile(pathToMLAPP, 'backgroundgame.png');
            app.secondbg = uiimage(app.UIFigure);
            app.secondbg.Visible = 'off';
            app.secondbg.ImageSource = fullfile(pathToMLAPP, 'bg2.png');
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Visible = 'off';
            app.RollDiceButton.Text = 'Roll Dice';
            app.dice1 = uiimage(app.UIFigure);
            app.dice1.Visible = 'off';
            app.dice1.ImageSource = fullfile(pathToMLAPP, 'one.png');
            app.dice5 = uiimage(app.UIFigure);
            app.dice5.Visible = 'off';
            app.dice5.ImageSource = fullfile(pathToMLAPP, 'five.png');
            app.dice6 = uiimage(app.UIFigure);
            app.dice6.Visible = 'off';
            app.dice6.ImageSource = fullfile(pathToMLAPP, 'six.png');
            app.dice4 = uiimage(app.UIFigure);
            app.dice4.Visible = 'off';
            app.dice4.ImageSource = fullfile(pathToMLAPP, 'four.png');
            app.dice3 = uiimage(app.UIFigure);
            app.dice3.Visible = 'off';
            app.dice3.ImageSource = fullfile(pathToMLAPP, 'two.png');
            app.dice2 = uiimage(app.UIFigure);
            app.dice2.Visible = 'off';
            app.dice2.ImageSource = fullfile(pathToMLAPP, 'two.png');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player 1'; 'Player 2'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Enable = 'off';
            app.SkipButton.Visible = 'off';
            app.SkipButton.Text = 'Skip';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiceGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end